classdef gameUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        StartGameButton      matlab.ui.control.StateButton
        Image                matlab.ui.control.Image
        ScoreEditField       matlab.ui.control.NumericEditField
        ScoreEditFieldLabel  matlab.ui.control.Label
        UIAxes               matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        % Keeps track of the current game state 
        % 0 = Game not running; 1 = Game running
        gameState; 
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: StartGameButton
        function StartButtonPressed(app, event)
            % value is 0 on default/unpressed, 1 when pressed
            app.gameState = app.StartGameButton.Value; 
            if app.gameState == 0
                app.StartGameButton.Text = "Start Game";
                app.Image.Visible = "off";
            else
                app.StartGameButton.Text = "End Game";
                app.Image.Visible = "on";
            end   
        end

        % Key press function: UIFigure
        function UIFigureKeyPress(app, event)
            key = event.Key;
            initialY = 100; % Initial Y position
            jumpHeight = 100; % Height to jump
            velocity = 5; % Velocity of jump

            if strcmp(key, 'space') % Check if spacebar is pressed
                % Get the current position of the image
                currentPosition = app.Image.Position;
                currentY = currentPosition(2);
                
                % Jump animation
                while currentY < initialY + jumpHeight
                    % Move the image up
                    currentY = currentY + velocity;
                    app.Image.Position = [currentPosition(1) currentY currentPosition(3:4)];
                    drawnow; % Update the figure
                end

                % Come back down
                while currentY > initialY
                    % Move the image down
                    currentY = currentY - velocity
                    app.Image.Position = [currentPosition(1) currentY currentPosition(3:4)];
                    drawnow; % Update the figure
                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.KeyPressFcn = createCallbackFcn(app, @UIFigureKeyPress, true);
            app.UIAxes = uiaxes(app.UIFigure);
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.ScoreEditFieldLabel = uilabel(app.UIFigure);
            app.ScoreEditFieldLabel.Text = 'Score';
            app.ScoreEditField = uieditfield(app.UIFigure, 'numeric');
            app.Image = uiimage(app.UIFigure);
            app.Image.Visible = 'off';
            app.Image.ImageSource = fullfile(pathToMLAPP, 'mickey_mouse.png');
            app.StartGameButton = uibutton(app.UIFigure, 'state');
            app.StartGameButton.ValueChangedFcn = createCallbackFcn(app, @StartButtonPressed, true);
            app.StartGameButton.Text = 'Start Game';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = gameUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end